classdef PGZ_decoder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        EditField_2              matlab.ui.control.EditField
        syndromesEditField       matlab.ui.control.EditField
        syndromesEditFieldLabel  matlab.ui.control.Label
        EditField                matlab.ui.control.EditField
        cCheckBox_2              matlab.ui.control.CheckBox
        cCheckBox                matlab.ui.control.CheckBox
        cxCheckBox               matlab.ui.control.CheckBox
        infoEditField            matlab.ui.control.EditField
        infoEditFieldLabel       matlab.ui.control.Label
        cdecEditField            matlab.ui.control.EditField
        cdecEditFieldLabel       matlab.ui.control.Label
        eEditField               matlab.ui.control.EditField
        eEditFieldLabel          matlab.ui.control.Label
        cEditField               matlab.ui.control.EditField
        cEditFieldLabel          matlab.ui.control.Label
        SeedeEditField           matlab.ui.control.NumericEditField
        SeedeEditFieldLabel      matlab.ui.control.Label
        SeedcEditField           matlab.ui.control.NumericEditField
        SeedcEditFieldLabel      matlab.ui.control.Label
        NEditField               matlab.ui.control.NumericEditField
        NEditFieldLabel          matlab.ui.control.Label
        dEditField               matlab.ui.control.NumericEditField
        dEditFieldLabel          matlab.ui.control.Label
        LetsgoButton             matlab.ui.control.Button
        qEditField               matlab.ui.control.NumericEditField
        qEditFieldLabel          matlab.ui.control.Label
    end


    properties (Access = public)
        GenerateButton    matlab.ui.control.Button
        SeedEditField     matlab.ui.control.NumericEditField
        SeedEditFieldLabel matlab.ui.control.Label
        InfoTextArea      matlab.ui.control.TextArea
        CodedTextArea     matlab.ui.control.TextArea
        ErrorTextArea     matlab.ui.control.TextArea
        DecodedTextArea   matlab.ui.control.TextArea
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LetsgoButton
        function LetsgoButtonPushed(app, event)
            
            if ~app.cxCheckBox.Value && ~app.cCheckBox.Value && ~app.cCheckBox_2.Value

                disp('Please check at least one checkbox.');
                app.EditField_2.Value = 'Please check at least one checkbox.'; 
                app.EditField.FontColor = [1 0 0]; % Red color

                return; 
            end

        q = app.qEditField.Value;
        d = app.dEditField.Value;
        N = app.NEditField.Value;
        if q<=2
          app.EditField_2.Value = 'Incorrect combination of input parameters: q cannot be smaller than 2'; 
          app.EditField_2.FontColor = [1 0 0]; % Red color
           app.qEditField.FontColor = [1 0 0];


        else
        m = ceil(log2(q)); 
        alpha = gf(2, m);  
        n = q-1;
        k = n-d+1;  
        t = floor((d-1)/2);
        seedc = app.SeedcEditField.Value;
        seede = app.SeedeEditField.Value;
   
 if d > n
            app.EditField_2.Value = 'Incorrect combination of input parameters: d cannot be larger than q-1';
            app.cEditField.Value = '';
            app.infoEditField.Value = '';
            app.eEditField.Value = '';
            app.cdecEditField.Value ='';
            app.syndromesEditField.Value = '';
           app.EditField_2.FontColor = [1 0 0]; % Red color
           app.dEditField.FontColor = [1 0 0];
           app.qEditField.FontColor = [1 0 0];

 else
        % Perform encoding and decoding
        
        [a, c] = codewort_generator(alpha, q, m, n, k, t, seedc);
        [v, e] = received_cw_generator(c, n, q, m, N, seede);

%% Decoding
        [S, tm_e] = syndrome_evaluation(alpha, t, m, n, v);            % Syndrome evaluation

        if tm_e     
            [Lambda, no_solution] = error_locator_polynomial(S, m, t); % Get coefficients of error locator polynomial
        else
            e_red = 0;      % If there was no error while transmitting --> e(x) = 0
        end

        if tm_e && ~no_solution
         e_red = calc_error_poly(Lambda, S, alpha, m, n);     % Calculate error polynomial
        end

% Reconstruct cw and extract information bits
  

        c_red = v-e_red;        
        a_red = c_red.x(1:k);

        if d > n
            app.EditField_2.Value = 'Incorrect combination of input parameters: d cannot be larger than q-1';
            app.cEditField.Value = '';
            app.infoEditField.Value = '';
            app.eEditField.Value = '';
            app.cdecEditField.Value ='';
            app.syndromesEditField.Value = '';
           app.EditField_2.FontColor = [1 0 0]; % Red color
           app.dEditField.FontColor = [1 0 0];
           app.qEditField.FontColor = [1 0 0];


      else
             app.EditField_2.Value = ''; %remove what is written if this is not the case anymore
            if app.cxCheckBox.Value
                     disp_mode = 1;
            elseif app.cCheckBox.Value
                     disp_mode = 2;
            elseif app.cCheckBox_2.Value
                     disp_mode = 3;
            end
                app.cEditField.Value = gf_to_string(c, alpha, m, disp_mode);
                app.infoEditField.Value = gf_to_string(gf(a, m), alpha, m, disp_mode);
                app.eEditField.Value = gf_to_string(e, alpha, m, disp_mode);
                app.cdecEditField.Value = gf_to_string(c_red, alpha, m, disp_mode);
                app.syndromesEditField.Value =  gf_to_string(S, alpha, m, disp_mode);
                
              app.EditField.FontColor = [0 0 0];
              app.dEditField.FontColor = [0 0 0];
           app.qEditField.FontColor = [0 0 0];


        end

        if e_red == 0
            app.EditField_2.Value = 'No error detected';
            % Set font color of EditField to grey
app.EditField_2.FontColor = [0 0 1];


% Set font color of other components to grey as needed

        end
        end
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.qEditFieldLabel = uilabel(app.UIFigure);
            app.qEditFieldLabel.Text = 'q';
            app.qEditField = uieditfield(app.UIFigure, 'numeric');
            app.qEditField.Value = 8;
            app.LetsgoButton = uibutton(app.UIFigure, 'push');
            app.LetsgoButton.ButtonPushedFcn = createCallbackFcn(app, @LetsgoButtonPushed, true);
            app.LetsgoButton.Text = 'Lets go';
            app.dEditFieldLabel = uilabel(app.UIFigure);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.UIFigure, 'numeric');
            app.dEditField.Value = 3;
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditField.Value = 1;
            app.SeedcEditFieldLabel = uilabel(app.UIFigure);
            app.SeedcEditFieldLabel.Text = 'Seed c';
            app.SeedcEditField = uieditfield(app.UIFigure, 'numeric');
            app.SeedcEditField.Value = 42;
            app.SeedeEditFieldLabel = uilabel(app.UIFigure);
            app.SeedeEditFieldLabel.Text = 'Seed e';
            app.SeedeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SeedeEditField.Value = 42;
            app.cEditFieldLabel = uilabel(app.UIFigure);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.UIFigure, 'text');
            app.eEditFieldLabel = uilabel(app.UIFigure);
            app.eEditFieldLabel.Text = 'e';
            app.eEditField = uieditfield(app.UIFigure, 'text');
            app.cdecEditFieldLabel = uilabel(app.UIFigure);
            app.cdecEditFieldLabel.Text = 'c dec';
            app.cdecEditField = uieditfield(app.UIFigure, 'text');
            app.infoEditFieldLabel = uilabel(app.UIFigure);
            app.infoEditFieldLabel.Text = 'info';
            app.infoEditField = uieditfield(app.UIFigure, 'text');
            app.cxCheckBox = uicheckbox(app.UIFigure);
            app.cxCheckBox.Text = 'c(x)';
            app.cCheckBox = uicheckbox(app.UIFigure);
            app.cCheckBox.Text = 'c(α)';
            app.cCheckBox_2 = uicheckbox(app.UIFigure);
            app.cCheckBox_2.Text = 'c ';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'Please pick an output format';
            app.syndromesEditFieldLabel = uilabel(app.UIFigure);
            app.syndromesEditFieldLabel.Text = 'syndromes';
            app.syndromesEditField = uieditfield(app.UIFigure, 'text');
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PGZ_decoder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end